classdef GUI_ccece_swarm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUISwarmUIFigure                matlab.ui.Figure
        SimulationSwitch                matlab.ui.control.Switch
        AgentsSpinnerLabel              matlab.ui.control.Label
        AgentsSpinner                   matlab.ui.control.Spinner
        MapviewCheckBox                 matlab.ui.control.CheckBox
        TTLsEditFieldLabel              matlab.ui.control.Label
        TTLsEditField                   matlab.ui.control.NumericEditField
        Orientation180NKnob             matlab.ui.control.Knob
        GeneratestatsCheckBox           matlab.ui.control.CheckBox
        InteragentdistancemSliderLabel  matlab.ui.control.Label
        InteragentdistancemSlider       matlab.ui.control.Slider
        ReferencespeedmsSliderLabel     matlab.ui.control.Label
        ReferencespeedmsSlider          matlab.ui.control.Slider
        ZoneoverlapsCheckBox            matlab.ui.control.CheckBox
        CurvilineartrajectoriesCheckBox  matlab.ui.control.CheckBox
        CollisionavoidanceCheckBox      matlab.ui.control.CheckBox
        RandomradiiCheckBox             matlab.ui.control.CheckBox
        OverlapsSlider                  matlab.ui.control.Slider
        OverlapsSliderLabel             matlab.ui.control.Label
        SparsitySlider                  matlab.ui.control.Slider
        SparsitySliderLabel             matlab.ui.control.Label
    end

    properties (Access = public)
        sim_time = 100;                 % default as 100 seconds
        simulation = 0;                 
        %ICC-paper
        %drone_type = "quadcopter";   
        drone_type = "point_mass";   
        
        nb_agents = 1;                  % number of drones in the simulation    
        
        wind = false;                   % default as false
        wind_gust = false;              % default as false
        wind_level = 0;                 % default as 0
        wind_gust_level = 0;            % default as 0
        
        active_environment = true;      % default as true

        %ICC-paper
        %swarming_algo = "vicsek";      % default as Vicsek
        swarming_algo = "icc_paper";    % default as icc_paper
        orientation = 220;              % default as North
        d_ref = 10                      % default as 10  
        v_ref = 6                       % default as 6
        
        debug_plot = false;             % default as false
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.VicsekButton.Enable = 'On';
            %app.OlfatiSaberButton.Enable = 'On';
            app.AgentsSpinner.Enable = 'On';
            app.nb_agents = 1;
            app.MapviewCheckBox.Enable = 'On';
            app.Orientation180NKnob.Enable = 'On';
            app.InteragentdistancemSlider.Enable = 'On';
            app.ReferencespeedmsSlider.Enable = 'On';
        end

        % Callback function
        function DronetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DronetypeButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Quadcopter'
                    app.drone_type = "quadcopter";
                case 'Point-mass'
                    app.drone_type = "point_mass";
            end
        end

        % Value changed function: SimulationSwitch
        function SimulationSwitchValueChanged(app, event)
            value = app.SimulationSwitch.Value;
            switch value
                case 'On'
%                     app.AgentsSpinner.Enable = 'Off';
%                     app.QuadcopterButton_2.Enable = 'Off';
%                     app.FixedwingButton.Enable = 'Off';
%                     app.FilletButton.Enable = 'Off';
%                     app.DubinButton.Enable = 'Off';
%                     app.DubinCityButton.Enable = 'Off';
%                     app.StraightRRTButton.Enable = 'Off';
%                     app.CoverRRTDubinButton.Enable = 'Off';
                    switch app.swarming_algo
                        case "vicsek"
                            example_vicsek(); 
                        case "olfati_saber"
                            example_olfati_saber();
                        case "icc_paper"
                            example_icc_paper();
                    end
                case 'Off'
%                     app.AgentsSpinner.Enable = 'On';
%                     app.QuadcopterButton_2.Enable = 'On';
%                     app.FixedwingButton.Enable = 'On';
%                     app.FilletButton.Enable = 'On';
%                     app.DubinButton.Enable = 'On';
%                     app.DubinCityButton.Enable = 'On';
%                     app.StraightRRTButton.Enable = 'On';
%                     app.CoverRRTDubinButton.Enable = 'On';
%                     close all;
                    
            end
        end

        % Value changed function: AgentsSpinner
        function AgentsSpinnerValueChanged(app, event)
            app.nb_agents = app.AgentsSpinner.Value;
        end

        % Callback function
        function AddwindCheckBoxValueChanged(app, event)
            app.wind = app.AddwindCheckBox.Value;
            if app.wind == false
                app.WindSlider.Enable = 'Off';
            else
                app.WindSlider.Enable = 'On';
            end
        end

        % Value changed function: MapviewCheckBox
        function MapviewCheckBoxValueChanged(app, event)
            app.active_environment = app.MapviewCheckBox.Value;
        end

        % Callback function
        function WindSliderValueChanged(app, event)
            app.wind_level = app.WindSlider.Value;
        end

        % Callback function
        function AddgustsCheckBoxValueChanged(app, event)
            app.wind_gust = app.AddgustsCheckBox.Value;
            if app.wind_gust == false
                app.WindSlider_2.Enable = 'Off';
            else
                app.WindSlider_2.Enable = 'On';
            end
        end

        % Callback function
        function WindSlider_2ValueChanged(app, event)
            app.wind_gust_level = app.WindSlider_2.Value;
        end

        % Callback function
        function SwarmingalgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.SwarmingalgorithmButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Vicsek'
                    app.swarming_algo = "vicsek";
                case 'Olfati-Saber'
                    app.swarming_algo = "olfati_saber";
            end
        end

        % Value changed function: TTLsEditField
        function TTLsEditFieldValueChanged(app, event)
            app.sim_time = app.TTLsEditField.Value;
        end

        % Callback function
        function SimulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Vicsek'
                    app.simulation = 0;
                    app.VicsekButton.Enable = 'On';
                    app.OlfatiSaberButton.Enable = 'On';
                    app.AgentsSpinner.Enable = 'On';
                    app.nb_agents = app.AgentsSpinner.Value;
                    app.MapviewCheckBox.Enable = 'On';
                    app.Orientation180NKnob.Enable = 'On';
                    app.d_ref = app.InteragentdistancemSlider.Value;
                    app.v_ref = app.ReferencespeedmsSlider.Value;
                case 'Olfati-Saber'
                    app.simulation = 1;
                    app.VicsekButton.Enable = 'Off';
                    app.OlfatiSaberButton.Enable = 'Off';
                    app.AgentsSpinner.Enable = 'On';
                    app.nb_agents = app.AgentsSpinner.Value;
                    app.MapviewCheckBox.Enable = 'On';
                    app.Orientation180NKnob.Enable = 'Off';
                    app.d_ref = app.InteragentdistancemSlider.Value;
                    app.v_ref = app.ReferencespeedmsSlider.Value;
            end
        end

        % Value changed function: Orientation180NKnob
        function Orientation180NKnobValueChanged(app, event)
            app.orientation = app.Orientation180NKnob.Value;
        end

        % Value changing function: Orientation180NKnob
        function Orientation180NKnobValueChanging(app, event)
            app.orientation = event.Value;
        end

        % Value changed function: GeneratestatsCheckBox
        function GeneratestatsCheckBoxValueChanged(app, event)
            app.debug_plot = app.GeneratestatsCheckBox.Value;
        end

        % Value changed function: InteragentdistancemSlider
        function InteragentdistancemSliderValueChanged(app, event)
            app.d_ref = app.InteragentdistancemSlider.Value;
        end

        % Value changed function: ReferencespeedmsSlider
        function ReferencespeedmsSliderValueChanged(app, event)
            app.v_ref = app.ReferencespeedmsSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUISwarmUIFigure = uifigure('Visible', 'off');
            app.GUISwarmUIFigure.Name = 'GUI Swarm';
            app.SimulationSwitch = uiswitch(app.GUISwarmUIFigure, 'slider');
            app.SimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @SimulationSwitchValueChanged, true);
            app.AgentsSpinnerLabel = uilabel(app.GUISwarmUIFigure);
            app.AgentsSpinnerLabel.Text = 'Agents';
            app.AgentsSpinner = uispinner(app.GUISwarmUIFigure);
            app.AgentsSpinner.Limits = [1 50];
            app.AgentsSpinner.ValueChangedFcn = createCallbackFcn(app, @AgentsSpinnerValueChanged, true);
            app.AgentsSpinner.Value = 1;
            app.MapviewCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.MapviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapviewCheckBoxValueChanged, true);
            app.MapviewCheckBox.Text = 'Map view';
            app.MapviewCheckBox.Value = true;
            app.TTLsEditFieldLabel = uilabel(app.GUISwarmUIFigure);
            app.TTLsEditFieldLabel.Text = 'TTL [s]';
            app.TTLsEditField = uieditfield(app.GUISwarmUIFigure, 'numeric');
            app.TTLsEditField.Limits = [0 Inf];
            app.TTLsEditField.ValueChangedFcn = createCallbackFcn(app, @TTLsEditFieldValueChanged, true);
            app.TTLsEditField.Value = 100;
            app.Orientation180NKnob = uiknob(app.GUISwarmUIFigure, 'continuous');
            app.Orientation180NKnob.Limits = [0 360];
            app.Orientation180NKnob.MajorTicks = [0 90 180 270 360];
            app.Orientation180NKnob.MajorTickLabels = {'South', 'West', 'North', 'East', 'South'};
            app.Orientation180NKnob.ValueChangedFcn = createCallbackFcn(app, @Orientation180NKnobValueChanged, true);
            app.Orientation180NKnob.ValueChangingFcn = createCallbackFcn(app, @Orientation180NKnobValueChanging, true);
            app.Orientation180NKnob.Value = 220;
            app.GeneratestatsCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.GeneratestatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeneratestatsCheckBoxValueChanged, true);
            app.GeneratestatsCheckBox.Text = 'Generate stats';
            app.InteragentdistancemSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.InteragentdistancemSliderLabel.Text = 'Inter-agent distance [m]';
            app.InteragentdistancemSlider = uislider(app.GUISwarmUIFigure);
            app.InteragentdistancemSlider.Limits = [5 15];
            app.InteragentdistancemSlider.MajorTicks = [5 7 10 12.5 15];
            app.InteragentdistancemSlider.MajorTickLabels = {'5', '7.5', '10', '12.5', '15'};
            app.InteragentdistancemSlider.ValueChangedFcn = createCallbackFcn(app, @InteragentdistancemSliderValueChanged, true);
            app.InteragentdistancemSlider.Value = 10;
            app.ReferencespeedmsSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.ReferencespeedmsSliderLabel.Text = 'Reference speed [m/s]';
            app.ReferencespeedmsSlider = uislider(app.GUISwarmUIFigure);
            app.ReferencespeedmsSlider.Limits = [2 8];
            app.ReferencespeedmsSlider.MajorTicks = [2 4 6 8 10];
            app.ReferencespeedmsSlider.ValueChangedFcn = createCallbackFcn(app, @ReferencespeedmsSliderValueChanged, true);
            app.ReferencespeedmsSlider.Value = 8;
            app.ZoneoverlapsCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.ZoneoverlapsCheckBox.Text = 'Zone overlaps';
            app.CurvilineartrajectoriesCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.CurvilineartrajectoriesCheckBox.Text = 'Curvilinear trajectories';
            app.CollisionavoidanceCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.CollisionavoidanceCheckBox.Text = 'Collision avoidance';
            app.RandomradiiCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.RandomradiiCheckBox.Text = 'Random radii';
            app.OverlapsSlider = uislider(app.GUISwarmUIFigure);
            app.OverlapsSlider.Limits = [2 8];
            app.OverlapsSlider.MajorTicks = [2 4 6 8 10];
            app.OverlapsSlider.MajorTickLabels = {'Min.', '', '', 'Max.'};
            app.OverlapsSlider.Value = 6;
            app.OverlapsSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.OverlapsSliderLabel.Text = 'Overlaps';
            app.SparsitySlider = uislider(app.GUISwarmUIFigure);
            app.SparsitySlider.Limits = [2 8];
            app.SparsitySlider.MajorTicks = [2 4 6 8 10];
            app.SparsitySlider.MajorTickLabels = {'Min.', '', '', 'Max.'};
            app.SparsitySlider.Value = 8;
            app.SparsitySliderLabel = uilabel(app.GUISwarmUIFigure);
            app.SparsitySliderLabel.Text = 'Sparsity';
            % Show the figure after all components are created
            app.GUISwarmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_ccece_swarm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUISwarmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUISwarmUIFigure)
        end
    end
end